classdef PVISolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TemaLabel                  matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        EliminarParametrosPanel    matlab.ui.container.Panel
        FicheiroLabel              matlab.ui.control.Label
        deleteBt                   matlab.ui.control.Button
        ficheiroDrop               matlab.ui.control.DropDown
        GuardarParametrosPanel     matlab.ui.container.Panel
        savePath                   matlab.ui.control.EditField
        saveBrowse                 matlab.ui.control.Button
        CaminhoFicheiroLabel_2     matlab.ui.control.Label
        GuardarButton              matlab.ui.control.Button
        fileNameButton             matlab.ui.control.EditField
        NomeFicheiroLabel          matlab.ui.control.Label
        CarregarParametrosPanel    matlab.ui.container.Panel
        LoadButton                 matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        filePathText               matlab.ui.control.EditField
        CaminhoFicheiroLabel       matlab.ui.control.Label
        bgMetodos                  matlab.ui.container.ButtonGroup
        PMcolor                    matlab.ui.control.DropDown
        ODE45color                 matlab.ui.control.DropDown
        RK4color                   matlab.ui.control.DropDown
        RK2color                   matlab.ui.control.DropDown
        EulerMcolor                matlab.ui.control.DropDown
        Eulercolor                 matlab.ui.control.DropDown
        CorLabel                   matlab.ui.control.Label
        rbTodos                    matlab.ui.control.RadioButton
        rbPontoMedio               matlab.ui.control.RadioButton
        rbODE45                    matlab.ui.control.RadioButton
        rbRK4                      matlab.ui.control.RadioButton
        rbRK2                      matlab.ui.control.RadioButton
        rbEulerM                   matlab.ui.control.RadioButton
        rbEuler                    matlab.ui.control.RadioButton
        AjudaButton                matlab.ui.control.Button
        DemoButton                 matlab.ui.control.Button
        TabelaPanel                matlab.ui.container.Panel
        colortabelaLabel           matlab.ui.control.Label
        colorTabela                matlab.ui.control.DropDown
        GrficoPanel                matlab.ui.container.Panel
        EixosLabel                 matlab.ui.control.Label
        GrelhaLabel                matlab.ui.control.Label
        ExataLabel                 matlab.ui.control.Label
        FundoLabel                 matlab.ui.control.Label
        tabelaColor                matlab.ui.control.DropDown
        ExataSwitch                matlab.ui.control.Switch
        GrelhaSwitch               matlab.ui.control.Switch
        EixosSwitch                matlab.ui.control.Switch
        ResetButton                matlab.ui.control.Button
        bAtualizar                 matlab.ui.control.Button
        ProblemaValorinicialPanel  matlab.ui.container.Panel
        ftyLabel                   matlab.ui.control.Label
        CondioInicialPanel         matlab.ui.container.Panel
        y0Label                    matlab.ui.control.Label
        eY0                        matlab.ui.control.NumericEditField
        eF                         matlab.ui.control.EditField
        ParametrosPanel            matlab.ui.container.Panel
        NLabel                     matlab.ui.control.Label
        eN                         matlab.ui.control.NumericEditField
        BLabel                     matlab.ui.control.Label
        eB                         matlab.ui.control.NumericEditField
        eA                         matlab.ui.control.NumericEditField
        aLabel                     matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        OptionsMenu                matlab.ui.container.Menu
        SairMenu_2                 matlab.ui.container.Menu
        SobreMenu                  matlab.ui.container.Menu
        AutoresMenu                matlab.ui.container.Menu
        DocumentaoMenu             matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("apps\","functions\","saves\",'documents\');           %Adicona pastas ao PATH da APP
            SwitchValueChanged(app, [])                                    %Altera o esquema de cores
            bAtualizarButtonPushed(app, [])                                %Obter dados
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
        syms y(t)
        strF = app.eF.Value;
        
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;        
        n = app.eN.Value;
        y0 = app.eY0.Value;
        
        escolhabg = app.bgMetodos.SelectedObject;
        escolhaM = find([app.rbEuler, app.rbEulerM, app.rbRK2,...
                         app.rbRK4, app.rbODE45, app.rbPontoMedio,...
                         app.rbTodos]==escolhabg);
        erroFuncao = MException("MATLAB:bAtualizar:BadF",...               %Erro referente à função
                                "Introduza uma função em t e y");

        erroB = MException("MATLAB:bAtualizar:BadB",...                    %Erro referente ao valor de a e b
                                "Introduza um numero real e maior que a");

        erroN = MException("MATLAB:bAtualizar:BadN",...                    %Erro referente ao valor de n
                                "Introduza um numero inteiro");

        erroy0 = MException("MATLAB:bAtualizar:Bady0",...                    %Erro referente ao valor de y0
                                "Introduza um número diferente de 0 para y0");


        try
            
            try
                z = f(t,y);
                app.eF.BackgroundColor = "w";  
            catch
                app.eF.BackgroundColor = [1 0 0];
                throw(erroFuncao)          
            end

            if ~(isreal(b) && b > a)
                app.eB.BackgroundColor = [1 0 0];
                throw(erroB)
            else
                app.eB.BackgroundColor = [1 1 1];
            end


            if (mod(n,1))                                                  %Se n não for inteiro
                app.eN.BackgroundColor = [1 0.6784 0.7137];
                throw(erroN)
            else
                app.eN.BackgroundColor = [1 1 1];
            end

            yEuler = MEuler(f,a,b,n,y0);
            yEulerM = MEulerM(f,a,b,n,y0);
            yOde45 = MOde45(f,a,b,n,y0);
            yMPM = MPM(f,a,b,n,y0);
            yRK2 = MRK2(f,a,b,n,y0);
            yRK4 = MRK4(f,a,b,n,y0);

            if (y0 == 0)
                app.eY0.BackgroundColor = [1 0.6784 0.7137];
                throw(erroy0)
            else
                app.eY0.BackgroundColor = [1 1 1];
            end
            
            sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            
            g =@(t) eval(vectorize(char(sExata)));
            
            h = (b-a)/n;                                                   % Tamanho de cada subintervalo
            t = a:h:b;                                                     % Alocação de memória - Abcissas
            yExata = g(t);                                                 % Alocação de memória - Ordenadas
            
            erroEuler = abs(yExata - yEuler);
            erroEulerM = abs(yExata- yEulerM);
            erroOde45 = abs(yExata- yOde45);
            erroMPM = abs(yExata- yMPM);
            erroRK2 = abs(yExata- yRK2);
            erroRK4 = abs(yExata- yRK4);
            

            c1 = plot(app.UIAxes,t,yExata,"-ro");
            hold(app.UIAxes,"on")

            if (app.ExataSwitch.Value == "on")
                c1 = plot(app.UIAxes,t,yExata,"-ro");
                hold(app.UIAxes,"on")
            else
                delete(c1);
            end


            colorCode = {[0 0 0];[1 1 1];[0 0.4470 0.7410];[0.8500 0.3250 0.0980];[0.9290 0.6940 0.1250];[0.4940 0.1840 0.5560];[0.4660 0.6740 0.1880];[0.3010 0.7450 0.9330];[0.6350 0.0780 0.1840]};
            
            switch escolhaM
                case 1
                    plot(app.UIAxes,t,yEuler,'color',cell2mat(colorCode(str2num(app.Eulercolor.Value))))
                    legenda = {'Exata','Euler'};
                    global tabela;
                    tabela = [t.',yExata.',yEuler.',erroEuler.'];
                    nomeColunas = {'t','Exata','Euler','erroEuler'};
                case 2
                    plot(app.UIAxes,t,yEulerM,'color',cell2mat(colorCode(str2num(app.EulerMcolor.Value))))
                    legenda = {'Exata','Euler Melhorado'};
                    tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                    nomeColunas = {'t','Exata','EulerMelhorado','erroEulerMelhorado'};
                case 3
                    plot(app.UIAxes,t,yRK2,'color',cell2mat(colorCode(str2num(app.RK2color.Value))))
                    legenda = {'Exata','RK2'};
                    tabela = [t.',yExata.',yRK2.',erroRK2.'];
                    nomeColunas = {'t','Exata','RK2','erroRK2'};
                case 4
                    plot(app.UIAxes,t,yRK4,'color',cell2mat(colorCode(str2num(app.RK4color.Value))))
                    legenda = {'Exata','RK4'};
                    tabela = [t.',yExata.',yRK4.',erroRK4.'];
                    nomeColunas = {'t','Exata','RK4','erroRK4'};
                case 5
                    plot(app.UIAxes,t,yOde45,'color',cell2mat(colorCode(str2num(app.ODE45color.Value))))
                    legenda = {'Exata','ODE45'};
                    tabela = [t.',yExata.',yOde45.',erroOde45.'];
                    nomeColunas = {'t','Exata','ODE45','erroODE45'};
                case 6
                    plot(app.UIAxes,t,yMPM,'color',cell2mat(colorCode(str2num(app.PMcolor.Value))))
                    legenda = {'Exata','Ponto Médio'};
                    tabela = [t.',yExata.',yMPM.',erroMPM.'];
                    nomeColunas = {'t','Exata','Ponto Médio','erroPontoMedio'};
                case 7
                   plot(app.UIAxes,t,yEuler,'color',cell2mat(colorCode(str2num(app.Eulercolor.Value))))
                   plot(app.UIAxes,t,yEulerM,'color',cell2mat(colorCode(str2num(app.EulerMcolor.Value))))
                   plot(app.UIAxes,t,yRK2,'color',cell2mat(colorCode(str2num(app.RK2color.Value))))
                   plot(app.UIAxes,t,yRK4,'color',cell2mat(colorCode(str2num(app.RK4color.Value))))
                   plot(app.UIAxes,t,yOde45,'color',cell2mat(colorCode(str2num(app.ODE45color.Value))))
                   plot(app.UIAxes,t,yMPM,'color',cell2mat(colorCode(str2num(app.PMcolor.Value))))
                   legenda = {'Exata','Euler','EulerMelhorado','RK2','RK4','ODE45','Ponto Medio'};
                   tabela = [t.',yExata.',yEuler.',yEulerM.',yRK2.',yRK4.',yOde45.',yMPM.',...
                             erroEuler.',erroEulerM.',erroRK2.',erroRK4.',erroOde45.',erroMPM.']; 
                   nomeColunas = {'t','Exata','Euler','EulerMelhorado','RK2','RK4','ODE45','PontoMedio'...
                                  'erroEuler','erroEulerMelhorado','erroRK2','erroRK4','erroODE45','erroPontoMedio'}; 
            end
            
            legend(app.UIAxes, legenda) 
            hold(app.UIAxes,"off")
            grid(app.UIAxes,"on")
            
            T = array2table(tabela);
            app.UITable.Data = T; 
            app.UITable.ColumnName = nomeColunas;
                
        catch ME
            errordlg(ME.message,"ERRO","modal")               
        end
            
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores                                                        %Aplicação referente a informações sobre autores
        end

        % Value changed function: ExataSwitch
        function ExataSwitchValueChanged(app, event)
            value = app.ExataSwitch.Value;                                 %Mostrar ou não a Curva da exata
            bAtualizarButtonPushed(app,[]);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.eF.Value = "";                                             %Reiniciar todos os parametros do PVI
            app.eA.Value = 0;
            app.eB.Value = 0;
            app.eN.Value = 1;
            app.eY0.Value = 0;

            app.ExataSwitch.Value = "on";
            app.GrelhaSwitch.Value = "on";
            app.EixosSwitch.Value = "on";

            app.Eulercolor.Value = "1";
            app.EulerMcolor.Value = "2";
            app.RK2color.Value = "3";
            app.RK4color.Value = "4";
            app.ODE45color.Value = "5";
            app.PMcolor.Value = "6";
            
            app.rbEuler.Value = 1;
        end

        % Button pushed function: DemoButton
        function DemoButtonPushed(app, event)
            app.eF.Value = "5*t+y";                                        %Coloca automaticamente valores para demonstrar a aplicação
            app.eA.Value = 1;
            app.eB.Value = 3;
            app.eN.Value = 12;
            app.eY0.Value = 2;

            app.ExataSwitch.Value = "on";
            app.GrelhaSwitch.Value = "on";
            app.EixosSwitch.Value = "on";

            app.Eulercolor.Value = "1";
            app.EulerMcolor.Value = "2";
            app.RK2color.Value = "3";
            app.RK4color.Value = "4";
            app.ODE45color.Value = "5";
            app.PMcolor.Value = "6";
            
            app.rbTodos.Value = 1;

            bAtualizarButtonPushed(app,[]);
        end

        % Menu selected function: SairMenu_2
        function SairMenu_2Selected(app, event)
            SN = questdlg("Quer mesmo sair ?","SAIR","Sim","Nao","Sim");   %Fechar a aplicação
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Callback function
        function FicheirosMenuSelected(app, event)
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            fileName = app.fileNameButton.Value;                           %Guardar parametros em ficheiro
            currentPath = pwd;
            savePathL = "\saves";
            filePath = currentPath + savePathL + "\" + fileName;

            f = app.eF.Value;
            a = app.eA.Value;
            b = app.eB.Value;        
            n = app.eN.Value;
            y0 = app.eY0.Value;

            if (app.savePath.Value ~= "")
                filePath2 = app.savePath.Value;
                filePath = filePath2 + "\" + fileName;
            end
            
            strQuest = "O ficheiro " + fileName + " já existe, deseja guardar mesmo assim ?";
    
            if (isfile(filePath + ".mat"))
                popWin = questdlg(strQuest,"Guardar Ficheiro","Sim","Nao","Sim");
                if strcmp(popWin,"Sim")
                    save(filePath,"f","a","b","n","y0");
                end
            else
                save(filePath,"f","a","b","n","y0");
            end


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            load(app.filePathText.Value);                                  %Carregar parametros a partir de ficheiro

            app.eF.Value = f;
            app.eA.Value = a;
            app.eB.Value = b;
            app.eN.Value = n;
            app.eY0.Value = y0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,~] = uigetfile('*.mat');                                 %Procurar ficheiro

            app.filePathText.Value = file;
        end

        % Button pushed function: saveBrowse
        function saveBrowseButtonPushed(app, event)
            defaultPath = pwd;                                             %Guardar parametros num ficheiro
            selPath = uigetdir(defaultPath,"Browse Folder");
            app.savePath.Value = selPath;
        end

        % Drop down opening function: ficheiroDrop
        function ficheiroDropDropDownOpening(app, event)
            files = dir("saves\*.mat");                                    %Procura ficheiros de dados na pasta "saves"
            disp({files.name})
            app.ficheiroDrop.Items = {files.name};
        end

        % Button pushed function: deleteBt
        function deleteBtButtonPushed(app, event)
            fileName = app.ficheiroDrop.Value;                             %Elimina ficheiro de parametros
            currF = pwd;
            filePath = currF + "\saves\" + fileName;

            strQuest = "Tem a certeza que deseja eliminar o ficheiro " + fileName;

            popWin = questdlg(strQuest,"Eliminar Ficheiro","Sim","Nao","Sim");
            if strcmp(popWin,"Sim")
                delete(filePath);
            end
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Value changed function: EixosSwitch
        function EixosSwitchValueChanged(app, event)
            value = app.EixosSwitch.Value;                                 %Comuta a visualização dos eixos no gráfico
            if (value == "on")
                axis(app.UIAxes,'on');
            else
                axis(app.UIAxes,'off');
            end
        end

        % Value changed function: GrelhaSwitch
        function GrelhaSwitchValueChanged(app, event)
            value = app.GrelhaSwitch.Value;                                %Comuta a visualização da grelha no gráfico
            if (value == "on")
                grid(app.UIAxes,'on');
            else
                grid(app.UIAxes,'off');
            end
        end

        % Value changed function: tabelaColor
        function tabelaColorValueChanged(app, event)
            value = app.tabelaColor.Value;                                 %Altera a cor do gráfico
            colors = {[1 1 1];[0.7215 0.7215 0.7215];[0.8078 0.8 0.8784];[0.8313 0.8784 0.9294];[0.9647 0.9450 0.8196];[0.8234 0.9372 0.8470]};
            
            app.UIAxes.Color = cell2mat(colors(str2num(value)));
        end

        % Value changed function: colorTabela
        function colorTabelaValueChanged(app, event)
            value = app.colorTabela.Value;                                 %Altera a cor da tabela
            colors = {[1 1 1];[0.7215 0.7215 0.7215];[0.8078 0.8 0.8784];[0.8313 0.8784 0.9294];[0.9647 0.9450 0.8196];[0.8234 0.9372 0.8470]};

            app.UITable.BackgroundColor = cell2mat(colors(str2num(value)));
        end

        % Menu selected function: DocumentaoMenu
        function DocumentaoMenuSelected(app, event)
            winopen("documents\RelatórioATV01 - PVISolver.pdf");                        %Abre o relatório referente à aplicação
        end

        % Callback function
        function PreferenciasMenuSelected(app, event)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;                                      %Altera o esquema de cores da aplicação
            if (value == "1")                                              %Tema escuro
                app.LeftPanel.BackgroundColor = [0.1921 0.1921 0.1921];
                app.RightPanel.BackgroundColor = [0.1921 0.1921 0.1921];

                app.ProblemaValorinicialPanel.BackgroundColor = [0.1921 0.1921 0.1921];
                app.bgMetodos.BackgroundColor = [0.1921 0.1921 0.1921];

                app.GrficoPanel.BackgroundColor = [0.2549 0.2549 0.2549];
                app.TabelaPanel.BackgroundColor = [0.2549 0.2549 0.2549];

                app.CarregarParametrosPanel.BackgroundColor = [0.3215 0.3215 0.3215];
                app.GuardarParametrosPanel.BackgroundColor = [0.3215 0.3215 0.3215];
                app.EliminarParametrosPanel.BackgroundColor = [0.3215 0.3215 0.3215];

                app.ParametrosPanel.BackgroundColor = [0.2549 0.2549 0.2549];
                app.CondioInicialPanel.BackgroundColor = [0.2549 0.2549 0.2549];

                app.ftyLabel.FontColor = [1 1 1];
                app.ProblemaValorinicialPanel.ForegroundColor = [1 1 1];
                app.ParametrosPanel.ForegroundColor = [1 1 1];
                app.CondioInicialPanel.ForegroundColor = [1 1 1];
                app.bgMetodos.ForegroundColor = [1 1 1];
                app.GrficoPanel.ForegroundColor = [1 1 1];
                app.TabelaPanel.ForegroundColor = [1 1 1];
                app.CarregarParametrosPanel.ForegroundColor = [1 1 1];
                app.GuardarParametrosPanel.ForegroundColor = [1 1 1];
                app.EliminarParametrosPanel.ForegroundColor = [1 1 1];
                app.UIAxes.XColor = [1 1 1];
                app.UIAxes.YColor = [1 1 1];
                app.eA.FontColor = [1 1 1];
                app.BLabel.FontColor = [1 1 1];
                app.NLabel.FontColor = [1 1 1];
                app.y0Label.FontColor = [1 1 1];
                app.rbEuler.FontColor = [1 1 1];
                app.rbEulerM.FontColor = [1 1 1];
                app.rbRK2.FontColor = [1 1 1];
                app.rbRK4.FontColor = [1 1 1];
                app.rbODE45.FontColor = [1 1 1];
                app.rbPontoMedio.FontColor = [1 1 1];
                app.rbTodos.FontColor = [1 1 1];
                app.CorLabel.FontColor = [1 1 1];
                app.ExataSwitch.FontColor = [1 1 1];
                app.GrelhaSwitch.FontColor = [1 1 1];
                app.EixosSwitch.FontColor = [1 1 1];
                app.FundoLabel.FontColor = [1 1 1];
                app.GrelhaLabel.FontColor = [1 1 1];
                app.ExataLabel.FontColor = [1 1 1];
                app.EixosLabel.FontColor = [1 1 1];
                app.colortabelaLabel.FontColor = [1 1 1];
                app.CaminhoFicheiroLabel_2.FontColor = [1 1 1];
                app.NomeFicheiroLabel.FontColor = [1 1 1];
                app.CaminhoFicheiroLabel.FontColor = [1 1 1];
                app.ficheiroDrop.FontColor = [1 1 1];
                app.FicheiroLabel.FontColor = [1 1 1];
                app.TemaLabel.FontColor = [1 1 1];
                app.Switch.FontColor = [1 1 1];
            else                                                           %Tema claro
                app.LeftPanel.BackgroundColor = [0.9137 0.9254 0.9372];
                app.RightPanel.BackgroundColor = [0.9137 0.9254 0.9372];

                app.ProblemaValorinicialPanel.BackgroundColor = [0.9137 0.9254 0.9372];
                app.bgMetodos.BackgroundColor = [0.9137 0.9254 0.9372];

                app.GrficoPanel.BackgroundColor = [0.6784 0.7098 0.7411];
                app.TabelaPanel.BackgroundColor = [0.6784 0.7098 0.7411];

                app.CarregarParametrosPanel.BackgroundColor = [0.8078 0.8313 0.8549];
                app.GuardarParametrosPanel.BackgroundColor = [0.8078 0.8313 0.8549];
                app.EliminarParametrosPanel.BackgroundColor = [0.8078 0.8313 0.8549];

                app.ParametrosPanel.BackgroundColor = [0.8078 0.8313 0.8549];
                app.CondioInicialPanel.BackgroundColor = [0.8078 0.8313 0.8549];

                app.ftyLabel.FontColor = [0 0 0];
                app.ProblemaValorinicialPanel.ForegroundColor = [0 0 0];
                app.ParametrosPanel.ForegroundColor = [0 0 0];
                app.CondioInicialPanel.ForegroundColor = [0 0 0];
                app.bgMetodos.ForegroundColor = [0 0 0];
                app.GrficoPanel.ForegroundColor = [0 0 0];
                app.TabelaPanel.ForegroundColor = [0 0 0];
                app.CarregarParametrosPanel.ForegroundColor = [0 0 0];
                app.GuardarParametrosPanel.ForegroundColor = [0 0 0];
                app.EliminarParametrosPanel.ForegroundColor = [0 0 0];
                app.UIAxes.XColor = [0 0 0];
                app.UIAxes.YColor = [0 0 0];
                app.eA.FontColor = [0 0 0];
                app.BLabel.FontColor = [0 0 0];
                app.NLabel.FontColor = [0 0 0];
                app.y0Label.FontColor = [0 0 0];
                app.rbEuler.FontColor = [0 0 0];
                app.rbEulerM.FontColor = [0 0 0];
                app.rbRK2.FontColor = [0 0 0];
                app.rbRK4.FontColor = [0 0 0];
                app.rbODE45.FontColor = [0 0 0];
                app.rbPontoMedio.FontColor = [0 0 0];
                app.rbTodos.FontColor = [0 0 0];
                app.CorLabel.FontColor = [0 0 0];
                app.ExataSwitch.FontColor = [0 0 0];
                app.GrelhaSwitch.FontColor = [0 0 0];
                app.EixosSwitch.FontColor = [0 0 0];
                app.FundoLabel.FontColor = [0 0 0];
                app.GrelhaLabel.FontColor = [0 0 0];
                app.ExataLabel.FontColor = [0 0 0];
                app.EixosLabel.FontColor = [0 0 0];
                app.colortabelaLabel.FontColor = [0 0 0];
                app.CaminhoFicheiroLabel_2.FontColor = [0 0 0];
                app.NomeFicheiroLabel.FontColor = [0 0 0];
                app.CaminhoFicheiroLabel.FontColor = [0 0 0];
                app.ficheiroDrop.FontColor = [0 0 0];
                app.FicheiroLabel.FontColor = [0 0 0];
                app.TemaLabel.FontColor = [0 0 0];
                app.Switch.FontColor = [0 0 0];
            end
        end

        % Button pushed function: AjudaButton
        function AjudaButtonPushed(app, event)
            winopen("documents\RelatórioATV01 - PVISolver.pdf");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {574, 574};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {787, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ProblemaValorinicialPanel = uipanel(app.LeftPanel);
            app.ProblemaValorinicialPanel.Title = 'Problema Valor inicial';
            app.ParametrosPanel = uipanel(app.ProblemaValorinicialPanel);
            app.ParametrosPanel.Title = 'Parametros';
            app.aLabel = uilabel(app.ParametrosPanel);
            app.aLabel.Text = 'A';
            app.eA = uieditfield(app.ParametrosPanel, 'numeric');
            app.eB = uieditfield(app.ParametrosPanel, 'numeric');
            app.eB.Value = 1.5;
            app.BLabel = uilabel(app.ParametrosPanel);
            app.BLabel.Text = 'B';
            app.eN = uieditfield(app.ParametrosPanel, 'numeric');
            app.eN.Limits = [1 Inf];
            app.eN.Value = 10;
            app.NLabel = uilabel(app.ParametrosPanel);
            app.NLabel.Text = 'N';
            app.eF = uieditfield(app.ProblemaValorinicialPanel, 'text');
            app.eF.Value = '-2*t*y';
            app.CondioInicialPanel = uipanel(app.ProblemaValorinicialPanel);
            app.CondioInicialPanel.Title = 'Condição Inicial';
            app.eY0 = uieditfield(app.CondioInicialPanel, 'numeric');
            app.eY0.Value = 2;
            app.y0Label = uilabel(app.CondioInicialPanel);
            app.y0Label.Text = 'y0';
            app.ftyLabel = uilabel(app.ProblemaValorinicialPanel);
            app.ftyLabel.Text = 'f(t,y) = ';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrficoPanel = uipanel(app.LeftPanel);
            app.GrficoPanel.Title = 'Gráfico';
            app.EixosSwitch = uiswitch(app.GrficoPanel, 'slider');
            app.EixosSwitch.Items = {'off', 'on'};
            app.EixosSwitch.ValueChangedFcn = createCallbackFcn(app, @EixosSwitchValueChanged, true);
            app.EixosSwitch.Value = 'on';
            app.GrelhaSwitch = uiswitch(app.GrficoPanel, 'slider');
            app.GrelhaSwitch.Items = {'off', 'on'};
            app.GrelhaSwitch.ValueChangedFcn = createCallbackFcn(app, @GrelhaSwitchValueChanged, true);
            app.GrelhaSwitch.Value = 'on';
            app.ExataSwitch = uiswitch(app.GrficoPanel, 'slider');
            app.ExataSwitch.Items = {'off', 'on'};
            app.ExataSwitch.ValueChangedFcn = createCallbackFcn(app, @ExataSwitchValueChanged, true);
            app.ExataSwitch.Value = 'on';
            app.tabelaColor = uidropdown(app.GrficoPanel);
            app.tabelaColor.Items = {'Branco', 'Cinzento', 'Roxo', 'Azul', 'Amarelo', 'Verde'};
            app.tabelaColor.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.tabelaColor.ValueChangedFcn = createCallbackFcn(app, @tabelaColorValueChanged, true);
            app.tabelaColor.Value = '1';
            app.FundoLabel = uilabel(app.GrficoPanel);
            app.FundoLabel.Text = 'Fundo';
            app.ExataLabel = uilabel(app.GrficoPanel);
            app.ExataLabel.Text = 'Exata';
            app.GrelhaLabel = uilabel(app.GrficoPanel);
            app.GrelhaLabel.Text = 'Grelha';
            app.EixosLabel = uilabel(app.GrficoPanel);
            app.EixosLabel.Text = 'Eixos';
            app.TabelaPanel = uipanel(app.LeftPanel);
            app.TabelaPanel.Title = 'Tabela';
            app.colorTabela = uidropdown(app.TabelaPanel);
            app.colorTabela.Items = {'Branco', 'Cinzento', 'Roxo', 'Azul', 'Amarelo', 'Verde'};
            app.colorTabela.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.colorTabela.ValueChangedFcn = createCallbackFcn(app, @colorTabelaValueChanged, true);
            app.colorTabela.Value = '1';
            app.colortabelaLabel = uilabel(app.TabelaPanel);
            app.colortabelaLabel.Text = 'Fundo';
            app.DemoButton = uibutton(app.LeftPanel, 'push');
            app.DemoButton.ButtonPushedFcn = createCallbackFcn(app, @DemoButtonPushed, true);
            app.DemoButton.Text = 'Demo';
            app.AjudaButton = uibutton(app.LeftPanel, 'push');
            app.AjudaButton.ButtonPushedFcn = createCallbackFcn(app, @AjudaButtonPushed, true);
            app.AjudaButton.Text = 'Ajuda';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbPontoMedio = uiradiobutton(app.bgMetodos);
            app.rbPontoMedio.Text = 'Ponto Médio';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos';
            app.CorLabel = uilabel(app.bgMetodos);
            app.CorLabel.Text = 'Cor';
            app.Eulercolor = uidropdown(app.bgMetodos);
            app.Eulercolor.Items = {'Preto', 'Branco', 'Azul Escuro', 'Vermelho', 'Amarelo', 'Roxo', 'Verde', 'Azul Claro', 'Castanho'};
            app.Eulercolor.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Eulercolor.Value = '1';
            app.EulerMcolor = uidropdown(app.bgMetodos);
            app.EulerMcolor.Items = {'Preto', 'Branco', 'Azul Escuro', 'Vermelho', 'Amarelo', 'Roxo', 'Verde', 'Azul Claro', 'Castanho'};
            app.EulerMcolor.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.EulerMcolor.Value = '1';
            app.RK2color = uidropdown(app.bgMetodos);
            app.RK2color.Items = {'Preto', 'Branco', 'Azul Escuro', 'Vermelho', 'Amarelo', 'Roxo', 'Verde', 'Azul Claro', 'Castanho'};
            app.RK2color.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.RK2color.Value = '1';
            app.RK4color = uidropdown(app.bgMetodos);
            app.RK4color.Items = {'Preto', 'Branco', 'Azul Escuro', 'Vermelho', 'Amarelo', 'Roxo', 'Verde', 'Azul Claro', 'Castanho'};
            app.RK4color.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.RK4color.Value = '1';
            app.ODE45color = uidropdown(app.bgMetodos);
            app.ODE45color.Items = {'Preto', 'Branco', 'Azul Escuro', 'Vermelho', 'Amarelo', 'Roxo', 'Verde', 'Azul Claro', 'Castanho'};
            app.ODE45color.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.ODE45color.Value = '1';
            app.PMcolor = uidropdown(app.bgMetodos);
            app.PMcolor.Items = {'Preto', 'Branco', 'Azul Escuro', 'Vermelho', 'Amarelo', 'Roxo', 'Verde', 'Azul Claro', 'Castanho'};
            app.PMcolor.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.PMcolor.Value = '1';
            app.CarregarParametrosPanel = uipanel(app.LeftPanel);
            app.CarregarParametrosPanel.Title = 'Carregar Parametros';
            app.CaminhoFicheiroLabel = uilabel(app.CarregarParametrosPanel);
            app.CaminhoFicheiroLabel.Text = 'Caminho Ficheiro';
            app.filePathText = uieditfield(app.CarregarParametrosPanel, 'text');
            app.BrowseButton = uibutton(app.CarregarParametrosPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.CarregarParametrosPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Carregar';
            app.GuardarParametrosPanel = uipanel(app.LeftPanel);
            app.GuardarParametrosPanel.Title = 'Guardar Parametros';
            app.NomeFicheiroLabel = uilabel(app.GuardarParametrosPanel);
            app.NomeFicheiroLabel.Text = 'Nome Ficheiro';
            app.fileNameButton = uieditfield(app.GuardarParametrosPanel, 'text');
            app.GuardarButton = uibutton(app.GuardarParametrosPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.CaminhoFicheiroLabel_2 = uilabel(app.GuardarParametrosPanel);
            app.CaminhoFicheiroLabel_2.Text = 'Caminho Ficheiro';
            app.saveBrowse = uibutton(app.GuardarParametrosPanel, 'push');
            app.saveBrowse.ButtonPushedFcn = createCallbackFcn(app, @saveBrowseButtonPushed, true);
            app.saveBrowse.Text = 'Browse';
            app.savePath = uieditfield(app.GuardarParametrosPanel, 'text');
            app.EliminarParametrosPanel = uipanel(app.LeftPanel);
            app.EliminarParametrosPanel.Title = 'Eliminar Parametros';
            app.ficheiroDrop = uidropdown(app.EliminarParametrosPanel);
            app.ficheiroDrop.Items = {};
            app.ficheiroDrop.DropDownOpeningFcn = createCallbackFcn(app, @ficheiroDropDropDownOpening, true);
            app.ficheiroDrop.Value = {};
            app.deleteBt = uibutton(app.EliminarParametrosPanel, 'push');
            app.deleteBt.ButtonPushedFcn = createCallbackFcn(app, @deleteBtButtonPushed, true);
            app.deleteBt.Text = 'Eliminar';
            app.FicheiroLabel = uilabel(app.EliminarParametrosPanel);
            app.FicheiroLabel.Text = 'Ficheiro';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'Escuro', 'Claro'};
            app.Switch.ItemsData = {'1', '2'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2';
            app.TemaLabel = uilabel(app.LeftPanel);
            app.TemaLabel.Text = 'Tema';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Curva EDO')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.SairMenu_2 = uimenu(app.OptionsMenu);
            app.SairMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SairMenu_2Selected, true);
            app.SairMenu_2.Text = 'Sair';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.DocumentaoMenu = uimenu(app.SobreMenu);
            app.DocumentaoMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentaoMenuSelected, true);
            app.DocumentaoMenu.Text = 'Documentação';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PVISolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end