classdef autores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SairButton           matlab.ui.control.Button
        AutoresLabel         matlab.ui.control.Label
        ContactoButton_3     matlab.ui.control.Button
        ContactoButton_2     matlab.ui.control.Button
        ContactoButton       matlab.ui.control.Button
        LuisDuarteLabel      matlab.ui.control.Label
        CarolinaVelosoLabel  matlab.ui.control.Label
        BrunoGuiomarLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContactoButton
        function ContactoButtonPushed(app, event)
            clipboard('copy','a2021137345@isec.pt');
            msgbox('O email de Bruno Guiomar foi copiado!')
        end

        % Button pushed function: ContactoButton_2
        function ContactoButton_2Pushed(app, event)
            clipboard('copy','a2021140780@isec.pt');
            msgbox('O email de Carolina Veloso foi copiado!')
        end

        % Button pushed function: ContactoButton_3
        function ContactoButton_3Pushed(app, event)
            clipboard('copy','a2021137789@isec.pt');
            msgbox('O email de Luis Duarte foi copiado!')
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrunoGuiomarLabel = uilabel(app.UIFigure);
            app.BrunoGuiomarLabel.Text = 'Bruno Guiomar';
            app.CarolinaVelosoLabel = uilabel(app.UIFigure);
            app.CarolinaVelosoLabel.Text = 'Carolina Veloso';
            app.LuisDuarteLabel = uilabel(app.UIFigure);
            app.LuisDuarteLabel.Text = 'Luis Duarte';
            app.ContactoButton = uibutton(app.UIFigure, 'push');
            app.ContactoButton.ButtonPushedFcn = createCallbackFcn(app, @ContactoButtonPushed, true);
            app.ContactoButton.Text = 'Contacto';
            app.ContactoButton_2 = uibutton(app.UIFigure, 'push');
            app.ContactoButton_2.ButtonPushedFcn = createCallbackFcn(app, @ContactoButton_2Pushed, true);
            app.ContactoButton_2.Text = 'Contacto';
            app.ContactoButton_3 = uibutton(app.UIFigure, 'push');
            app.ContactoButton_3.ButtonPushedFcn = createCallbackFcn(app, @ContactoButton_3Pushed, true);
            app.ContactoButton_3.Text = 'Contacto';
            app.AutoresLabel = uilabel(app.UIFigure);
            app.AutoresLabel.Text = 'Autores';
            app.SairButton = uibutton(app.UIFigure, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end